classdef DefendTheNetwork < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        Label_4                      matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        Label                        matlab.ui.control.Label
        AttackerBurnoutRateSlider_2  matlab.ui.control.Slider
        AttackerBurnoutRateSlider_2Label  matlab.ui.control.Label
        DefenderGrowthRateSlider_2   matlab.ui.control.Slider
        DefenderGrowthRateSlider_2Label  matlab.ui.control.Label
        RunSimulationButton          matlab.ui.control.Button
        DifficultyDropDown           matlab.ui.control.DropDown
        DifficultyDropDownLabel      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        NetworkSecureLabel           matlab.ui.control.Label
        NetworkHealthLamp            matlab.ui.control.Lamp
        NetworkHealthLampLabel       matlab.ui.control.Label
        TimeDisplayLabel             matlab.ui.control.Label
        AttackerStatsLabel           matlab.ui.control.Label
        DefenderStatsLabel           matlab.ui.control.Label
        AttackersGauge               matlab.ui.control.SemicircularGauge
        AttackersGaugeLabel          matlab.ui.control.Label
        DefendersGauge               matlab.ui.control.SemicircularGauge
        DefendersGaugeLabel          matlab.ui.control.Label
        BattleAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            import javax.swing.SwingConstants

            % "Behind the Simulation" panel
            if isempty(findall(app.LeftPanel, 'Tag', 'BehindPanel'))

            % Collapsible Info Panel
            panel = uipanel(app.LeftPanel, ...
                'Title', '🧠 Behind the Simulation', ...
                'FontWeight', 'bold', ...
                'FontSize', 12, ...
                'Position', [13 60 324 95], ...
                'Scrollable', 'on', ...
                'Tag', 'BehindPanel');

            % Text label inside panel
            uilabel(panel, ...
                'Text', sprintf([ ...
                    'dx/dt = αx - βxy   (Attackers)\n', ...
                    'dy/dt = δxy - γy   (Defenders)\n\n', ...
                    'x = Attackers\n', ...
                    'y = Defenders']), ...
                'FontSize', 10, ...
                'Position', [10 0 300 80], ...
                'WordWrap', 'on');

             % Modal Button
            uibutton(app.LeftPanel, 'Text', 'ℹ️ Learn More', ...
                'Position', [240 35 95 22], ...
                'ButtonPushedFcn', @(src, event) showModal(app));
            end

            % Fix and format UITable columns during runtime
            try
                app.UITable.ColumnName = {'ATK (x)', 'DEF (y)', 'Δx = αx - βxy', 'Δy = δxy - γy'};
                app.UITable.ColumnWidth = {55, 55, 100, 100};
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric'};

                 % Center align columns using Java
                jScrollPane = findjobj(app.UITable);
                jTable = jScrollPane.getViewport.getView;
                for col = 0:jTable.getColumnCount()-1
                    renderer = jTable.getColumnModel.getColumn(col).getCellRenderer;
                    renderer.setHorizontalAlignment(SwingConstants.CENTER);
                end
            catch ME
                warning('CenteringWarning:TableUI', 'Failed to center-align UITable columns: %s', ME.message);
            end

            % Initial populations
            x0 = 9;   % Attackers
            y0 = 40;  % Defenders
            tspan = [0 50];

            % Slider values normalized to [0, 1]
            alpha = app.DefenderGrowthRateSlider_2.Value / 100;  % Defender growth
            gamma = app.AttackerBurnoutRateSlider_2.Value / 100; % Attacker decay

            % Attacker behavior based on difficulty
            switch app.DifficultyDropDown.Value
                case 'Easy'
                    beta = 0.015;
                    delta = 0.01;
                case 'Medium'
                    beta = 0.025;
                    delta = 0.015;
                case 'Hard'
                    beta = 0.035;
                    delta = 0.02;
            end

            % Lotka-Volterra differential system
            f = @(t, y) [
                alpha * y(1) - beta * y(1) * y(2);         % Attackers
                delta * y(1) * y(2) - gamma * y(2)         % Defenders
            ];

            % Solve
            [t, pop] = ode45(f, tspan, [x0; y0]);

            % Precompute differential equations for table
            dx = alpha .* pop(:,1) - beta .* pop(:,1) .* pop(:,2);   % Δx
            dy = delta .* pop(:,1) .* pop(:,2) - gamma .* pop(:,2);  % Δy

            % Build table data
            rowsToShow = min(50, length(t));  % Cap for visibility
            tableData = [pop(1:rowsToShow,1), pop(1:rowsToShow,2), dx(1:rowsToShow), dy(1:rowsToShow)];

            % Update the UITable with formatted values
            app.UITable.Data = round(tableData, 2);  % Round for clarity

            % Clear plot and start animation
            cla(app.BattleAxes);
            hold(app.BattleAxes, 'on');

            % Dynamic axis limits
            app.BattleAxes.XLim = [min(t), max(t)];
            maxPop = max(max(pop));
            roundedMax = ceil(maxPop / 20) * 20;
            app.BattleAxes.YLim = [0, roundedMax];

            % Dynamic ticks
            app.BattleAxes.XTick = linspace(min(t), max(t), 6);  % ~5 intervals (e.g., 0, 10, 20, 30, 40, 50)
            app.BattleAxes.YTick = linspace(0, roundedMax, 6);   % e.g., 0, 20, ..., max

            hDef = animatedline(app.BattleAxes, 'Color', 'b', 'LineWidth', 2);
            hAtk = animatedline(app.BattleAxes, 'Color', 'r', 'LineWidth', 2);

            % Animate over time
            for i = 1:length(t)
            % Update plots
            addpoints(hDef, t(i), pop(i,2));
            addpoints(hAtk, t(i), pop(i,1));

            % Update gauges
            app.DefendersGauge.Value = min(pop(i,2), 100);
            app.AttackersGauge.Value = min(pop(i,1), 100);

            % Update stats
            app.DefenderStatsLabel.Text = sprintf('Mean: %.1f  |  Final: %.1f', mean(pop(1:i,2)), pop(i,2));
            app.AttackerStatsLabel.Text = sprintf('Mean: %.1f  |  Final: %.1f', mean(pop(1:i,1)), pop(i,1));

            % Determine live status
            if pop(i,2) > pop(i,1) * 1.5
                liveStatus = 'Status: Holding';
                liveColor = [0.6 1 0.6];  % Light Green
            elseif pop(i,1) > pop(i,2) * 1.5
                liveStatus = 'Status: Under Attack';
                liveColor = [1 0.5 0.5];  % Light Red
            else
                liveStatus = 'Status: Contested';
                liveColor = [1 1 0.5];    % Light Yellow
            end

            % Apply updates
            app.NetworkHealthLamp.Color = liveColor;
            app.NetworkSecureLabel.Text = sprintf('%s\nFinal: Pending...', liveStatus);

            % Update time display
            app.TimeDisplayLabel.Text = sprintf('Time: %.1f', t(i));

            drawnow;
            pause(0.05);  % slows down simulation for animation effect
            end

            % Final network status based on mean values
            defenderMean = mean(pop(:,2));
            attackerMean = mean(pop(:,1));

            if defenderMean > attackerMean * 1.5
                finalStatus = '🛡️ Final: ✅ Network Secure';
                app.NetworkHealthLamp.Color = [0 1 0];  % Bold Green
            elseif attackerMean > defenderMean * 1.5
                finalStatus = '💀 Final: ❌ System Breached';
                app.NetworkHealthLamp.Color = [1 0 0];  % Bold Red
            else
                finalStatus = '⚠️ Final: ⚔️ Contested';
                app.NetworkHealthLamp.Color = [1 1 0];  % Bold Yellow
            end

            % Recompute live status from final values
            finalAttackers = pop(end,1);
            finalDefenders = pop(end,2);

            if finalDefenders > finalAttackers * 1.5
                finalLiveStatus = '🟢 Status: Holding';
            elseif finalAttackers > finalDefenders * 1.5
                finalLiveStatus = '🔴 Status: Under Attack';
            else
                finalLiveStatus = '🟡 Status: Contested';
            end

            % Update the label
            app.NetworkSecureLabel.Text = sprintf('%s\n%s', finalLiveStatus, finalStatus);

            % Finalize plot
            legend(app.BattleAxes, {'Defenders', 'Attackers'}, 'Location', 'northeast');
            title(app.BattleAxes, 'Cyber Battle: Defenders vs. Attackers');
            xlabel(app.BattleAxes, 'Time');
            ylabel(app.BattleAxes, 'Population (Attackers/Defenders)');
            hold(app.BattleAxes, 'off');

            function showModal(~)
                d = uifigure('Name', 'About This Simulation', 'Position', [100 100 480 400]);
                d.Color = [0.98 0.98 0.95];  % Light cream background

                msg = sprintf([ ...
                    'This simulation models a cybersecurity battle using predator-prey dynamics.\n\n', ...
                    '🛡️ Defenders: firewalls, analysts, detection systems\n', ...
                    '💀 Attackers: threats, exploits, malware\n\n', ...
                    'The equations reflect:\n', ...
                    '⚔️ Growth, burnout, and interaction between forces\n\n', ...
                    'dx/dt = αx - βxy → attackers increase, then decline when defenders push back\n', ...
                    'dy/dt = δxy - γy → defenders strengthen when active, but can wear out\n\n', ...
                    'Tweak parameters to test different strategies!' ]);

                uilabel(d, 'Text', msg, ...
                    'Position', [20 60 440 320], ...
                    'WordWrap', 'on', 'FontSize', 11);

                uibutton(d, 'Text', 'Close', ...
                    'Position', [195, 20, 90, 30], ...
                    'ButtonPushedFcn', @(src,event) close(d));
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {700, 700};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {345, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DifficultyDropDownLabel = uilabel(app.LeftPanel);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.LeftPanel);
            app.DifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.DifficultyDropDown.Tooltip = {'Controls attacker behavior: harder levels increase attack speed and decrease defender advantage.'};
            app.DifficultyDropDown.Value = 'Easy';
            app.RunSimulationButton = uibutton(app.LeftPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.DefenderGrowthRateSlider_2Label = uilabel(app.LeftPanel);
            app.DefenderGrowthRateSlider_2Label.Text = 'Defender Growth Rate (α)';
            app.DefenderGrowthRateSlider_2 = uislider(app.LeftPanel);
            app.DefenderGrowthRateSlider_2.Tooltip = {'How fast defenders build up resources or strengthen security measures.'};
            app.AttackerBurnoutRateSlider_2Label = uilabel(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2Label.Text = 'Attacker Burnout Rate (γ)';
            app.AttackerBurnoutRateSlider_2 = uislider(app.LeftPanel);
            app.AttackerBurnoutRateSlider_2.Tooltip = {'How quickly attackers lose steam or get blocked if they’re not successful.'};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'Difficulty adjusts how aggressive attackers are. Hard = faster attackers and greater defender stress.';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'ATK (x)'; 'DEF (y)'; '   Δx'; 'Δy'};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'Higher values = defenders recover faster and grow more rapidly.';
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'Higher values = attackers tire out quickly or fail faster.';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = 'Table shows attacker/defender populations and the change rates per step.';
            app.RightPanel = uipanel(app.GridLayout);
            app.BattleAxes = uiaxes(app.RightPanel);
            title(app.BattleAxes, 'Cyber Battle: Defender vs Attacker')
            xlabel(app.BattleAxes, 'Time')
            zlabel(app.BattleAxes, 'Z')
            app.BattleAxes.XTick = [0 2 4 6 8 10 12 24 36 48];
            app.BattleAxes.YTick = [0 20 40 60 80 100];
            app.DefendersGaugeLabel = uilabel(app.RightPanel);
            app.DefendersGaugeLabel.Text = 'Defenders';
            app.DefendersGauge = uigauge(app.RightPanel, 'semicircular');
            app.AttackersGaugeLabel = uilabel(app.RightPanel);
            app.AttackersGaugeLabel.Text = 'Attackers';
            app.AttackersGauge = uigauge(app.RightPanel, 'semicircular');
            app.DefenderStatsLabel = uilabel(app.RightPanel);
            app.DefenderStatsLabel.Text = 'DefenderStatsLabel';
            app.AttackerStatsLabel = uilabel(app.RightPanel);
            app.AttackerStatsLabel.Text = 'AttackerStatsLabel';
            app.TimeDisplayLabel = uilabel(app.RightPanel);
            app.TimeDisplayLabel.Text = 'Time: 0.0';
            app.NetworkHealthLampLabel = uilabel(app.RightPanel);
            app.NetworkHealthLampLabel.Text = 'Network Health';
            app.NetworkHealthLamp = uilamp(app.RightPanel);
            app.NetworkSecureLabel = uilabel(app.RightPanel);
            app.NetworkSecureLabel.Text = 'Network Secure';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DefendTheNetwork

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end